classdef crypto_trading < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BtnClose                        matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        TabTrading                      matlab.ui.container.Tab
        LabelParamSizePredict           matlab.ui.control.Label
        LabelBatchSizePredict           matlab.ui.control.Label
        LabelHiddenNodesPredict         matlab.ui.control.Label
        LabelWindowSizePredict          matlab.ui.control.Label
        LabelSLRatioPredict             matlab.ui.control.Label
        LabelTPRatioPredict             matlab.ui.control.Label
        batchsizeSpinnerLabel_3         matlab.ui.control.Label
        batchsizeSpinnerLabel_2         matlab.ui.control.Label
        hiddennodesSpinnerLabel_2       matlab.ui.control.Label
        windowsizeSpinnerLabel_2        matlab.ui.control.Label
        stoplossratioSpinnerLabel_2     matlab.ui.control.Label
        takeprofitratioSpinnerLabel_2   matlab.ui.control.Label
        takeprofitratioSpinnerLabel_3   matlab.ui.control.Label
        LoadedModelInfoLabel            matlab.ui.control.Label
        LabelLoadedModel                matlab.ui.control.Label
        BtnRemoveModel                  matlab.ui.control.Button
        BtnLoadModel                    matlab.ui.control.Button
        SavedModelLabel                 matlab.ui.control.Label
        ListBoxModels                   matlab.ui.control.ListBox
        PanelPredict                    matlab.ui.container.Panel
        LabelPLPercent                  matlab.ui.control.Label
        takeprofitratioSpinnerLabel_12  matlab.ui.control.Label
        LabelShartRatio                 matlab.ui.control.Label
        LabelProfitFactor               matlab.ui.control.Label
        LabelWinRate                    matlab.ui.control.Label
        LabelTradeCount                 matlab.ui.control.Label
        LabelMaxDrawdown                matlab.ui.control.Label
        LabelPLAbsolute                 matlab.ui.control.Label
        LabelFinalBalance               matlab.ui.control.Label
        SpinnerStartBalance             matlab.ui.control.Spinner
        takeprofitratioSpinnerLabel_11  matlab.ui.control.Label
        takeprofitratioSpinnerLabel_10  matlab.ui.control.Label
        takeprofitratioSpinnerLabel_9   matlab.ui.control.Label
        takeprofitratioSpinnerLabel_8   matlab.ui.control.Label
        takeprofitratioSpinnerLabel_7   matlab.ui.control.Label
        takeprofitratioSpinnerLabel_6   matlab.ui.control.Label
        takeprofitratioSpinnerLabel_4   matlab.ui.control.Label
        takeprofitratioSpinnerLabel_5   matlab.ui.control.Label
        BtnPredict                      matlab.ui.control.Button
        AxesPrediction                  matlab.ui.control.UIAxes
        BtnRemoveFile                   matlab.ui.control.Button
        BtnImportFile                   matlab.ui.control.Button
        DataLabel                       matlab.ui.control.Label
        ListBoxFiles                    matlab.ui.control.ListBox
        Panel                           matlab.ui.container.Panel
        SpinnerFs                       matlab.ui.control.Spinner
        FsSpinnerLabel                  matlab.ui.control.Label
        TableOriginal                   matlab.ui.control.Table
        TreeColumnsOriginal             matlab.ui.container.CheckBoxTree
        TreeNodeAll                     matlab.ui.container.TreeNode
        TreeNodeOpen                    matlab.ui.container.TreeNode
        TreeNodeClose                   matlab.ui.container.TreeNode
        TreeNodeHigh                    matlab.ui.container.TreeNode
        TreeNodeLow                     matlab.ui.container.TreeNode
        BtnGroupPlotTypeOriginal        matlab.ui.container.ButtonGroup
        BtnCandlestickOriginal          matlab.ui.control.RadioButton
        Btn2DPlotOriginal               matlab.ui.control.RadioButton
        BtnTableOriginal                matlab.ui.control.RadioButton
        BtnFFTOriginal                  matlab.ui.control.RadioButton
        AxesOriginal                    matlab.ui.control.UIAxes
        TabTraining                     matlab.ui.container.Tab
        EditFieldDropout                matlab.ui.control.NumericEditField
        dropoutLabel                    matlab.ui.control.Label
        EditFieldEpoch                  matlab.ui.control.NumericEditField
        epochEditFieldLabel             matlab.ui.control.Label
        BtnTrain                        matlab.ui.control.Button
        datasetDropDownLabel            matlab.ui.control.Label
        DropDownTrainingDataset         matlab.ui.control.DropDown
        LabelTestAccuracy               matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        EditFieldMomentumRate           matlab.ui.control.NumericEditField
        momentumrateEditFieldLabel      matlab.ui.control.Label
        EditFieldLearningRate           matlab.ui.control.NumericEditField
        learningrateEditFieldLabel      matlab.ui.control.Label
        BtnSaveModel                    matlab.ui.control.Button
        SpinnerHiddenNodes              matlab.ui.control.Spinner
        hiddennodesSpinnerLabel         matlab.ui.control.Label
        SpinnerBatchSize                matlab.ui.control.Spinner
        batchsizeSpinnerLabel           matlab.ui.control.Label
        SpinnerTrainRatio               matlab.ui.control.Spinner
        traindataratioSpinnerLabel      matlab.ui.control.Label
        SpinnerWindowSize               matlab.ui.control.Spinner
        windowsizeSpinnerLabel          matlab.ui.control.Label
        SpinnerSLRatio                  matlab.ui.control.Spinner
        stoplossratioSpinnerLabel       matlab.ui.control.Label
        SpinnerTPRatio                  matlab.ui.control.Spinner
        takeprofitratioSpinnerLabel     matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        AxesLoss                        matlab.ui.control.UIAxes
        LabelTitle                      matlab.ui.control.Label
    end


    properties (Access = public)
        isTraining = false;
        Wf = [];
        Rf = [];
        bf = [];
        Wi = [];
        Ri = [];
        bi = [];
        Wg = [];
        Rg = [];
        bg = [];
        Wo = [];
        Ro = [];
        bo = [];
        V = [];
        b = [];

        tpRatio = nan;
        slRatio = nan;
        windowSize = nan;
        batchSize = nan;
        H = nan;
    end

    methods (Access = private)

        function refreshFileList(app)
            dataFiles = dir(fullfile('./datasets', '*.parquet'));
            app.ListBoxFiles.Items =  {dataFiles.name}';
            app.DropDownTrainingDataset.Items = {dataFiles.name}';

            if ~isempty(dataFiles)
                % select default item
                app.ListBoxFiles.ValueIndex = 1;
                plotOriginalData(app);
            end
        end

        function refreshModelList(app)
            modelFiles = dir(fullfile('./models', '*.mat'));
            app.ListBoxModels.Items =  {modelFiles.name}';
        end

        function loadModel(app, selectedModel)
            foo = load(fullfile('./models', selectedModel));

            app.Wf = foo.Wf;
            app.Rf = foo.Rf;
            app.bf = foo.bf;
            app.Wi = foo.Wi;
            app.Ri = foo.Ri;
            app.bi = foo.bi;
            app.Wg = foo.Wg;
            app.Rg = foo.Rg;
            app.bg = foo.bg;
            app.Wo = foo.Wo;
            app.Ro = foo.Ro;
            app.bo = foo.bo;
            app.V = foo.V;
            app.b = foo.b;

            app.tpRatio = foo.tpRatio;
            app.slRatio = foo.slRatio;
            app.windowSize = foo.window;
            app.batchSize = foo.batchSize;
            app.H = foo.H;

            app.LabelLoadedModel.Text = selectedModel;
            app.LabelTPRatioPredict.Text = num2str(app.tpRatio);
            app.LabelSLRatioPredict.Text = num2str(app.slRatio);
            app.LabelWindowSizePredict.Text = num2str(app.windowSize);
            app.LabelBatchSizePredict.Text = num2str(app.batchSize);
            app.LabelHiddenNodesPredict.Text = num2str(app.H);

            paramSize = numel(app.Wf) + numel(app.Rf) + numel(app.bf) + ...
                numel(app.Wi) + numel(app.Ri) + numel(app.bi) + ...
                numel(app.Wg) + numel(app.Rg) + numel(app.bg) + ...
                numel(app.Wo) + numel(app.Ro) + numel(app.bo) + ...
                numel(app.V) + numel(app.b);

            app.LabelParamSizePredict.Text = num2str(paramSize);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            refreshFileList(app);
            refreshModelList(app);
            expand(app.TreeColumnsOriginal, 'all');
        end

        % Button pushed function: BtnClose
        function BtnClosePushed(app, event)
            app.delete
        end

        % Button pushed function: BtnImportFile
        function BtnImportFilePushed(app, event)
            [fileName, path, filterIndex] = uigetfile('*.parquet', 'Select a file to import.');

            if filterIndex == 0
                % close dialog
                figure(app.UIFigure);
                return;
            else
                if ischar(fileName) && isempty(fileName)
                    % No file selected, show alert
                    figure(app.UIFigure);
                    uialert(app.UIFigure, 'No file selected. Please select a file.', 'Warning', 'Icon', 'warning');
                else
                    % File selected, proceed with fileName and pathName
                    fullFilePath = fullfile(path, fileName);
                    destPath = './datasets';
                    destFile = fullfile(destPath, fileName);

                    % Check if destination file already exists
                    if exist(destFile, 'file')
                        % File exists, show confirmation dialog
                        figure(app.UIFigure)
                        choice = questdlg('File already exists. Overwrite?', 'Overwrite Confirmation', 'Yes', 'No', 'No');

                        % Handle user's choice
                        switch choice
                            case 'Yes'
                                % Overwrite the existing file
                                copyfile(fullFilePath, destPath);
                            case 'No'
                                % Do nothing, file not overwritten
                            otherwise
                                % User canceled, do nothing
                        end
                    else
                        % File doesn't exist, copy the file
                        copyfile(fullFilePath, destPath);
                    end

                    figure(app.UIFigure);
                    refreshFileList(app);
                end
            end
        end

        % Button pushed function: BtnRemoveFile
        function BtnRemoveFilePushed(app, event)
            selectedFile = app.ListBoxFiles.Value;
            if isempty(selectedFile)
                uialert(app.UIFigure, 'Please select file to remove.', 'Warning');
            else
                response = uiconfirm(app.UIFigure, ['Are you sure you want to remove ', selectedFile, '?'], 'Confirm Remove', 'Icon', 'warning', 'Options', {'Yes', 'No'}, 'DefaultOption', 2);

                if response == "Yes"
                    delete(fullfile('datasets', selectedFile));
                    uiconfirm(app.UIFigure, 'Sucessfully deleted file.', 'Sucess', 'Options',{'OK'});
                    refreshFileList(app);
                else
                    return
                end
            end
        end

        % Button pushed function: BtnSaveModel
        function BtnSaveModelPushed(app, event)
            modelPath = fullfile('./models', '*.mat');
            [name, path, filterIndex] = uiputfile(modelPath, 'Save App Variables');

            % If user clicked Cancel, exit
            if filterIndex == 0
                return

                % Otherwise
            else
                filePath = fullfile(path, name);

                tpRatio = app.SpinnerTPRatio.Value;
                slRatio = app.SpinnerSLRatio.Value;
                window = app.SpinnerWindowSize.Value;
                batchSize = app.SpinnerBatchSize.Value;
                H = app.SpinnerHiddenNodes.Value;

                Wf = app.Wf;
                Rf = app.Rf;
                bf = app.bf;
                Wi = app.Wi;
                Ri = app.Ri;
                bi = app.bi;
                Wg = app.Wg;
                Rg = app.Rg;
                bg = app.bg;
                Wo = app.Wo;
                Ro = app.Ro;
                bo = app.bo;
                V = app.V;
                b = app.b;

                save(filePath, 'Wf', 'Rf', 'bf', 'Wi', 'Ri', 'bi',...
                    'Wg', 'Rg', 'bg', 'Wo', 'Ro', 'bo', 'V', 'b', ...
                    'tpRatio', 'slRatio', 'window', 'batchSize', 'H');

                uiconfirm(app.UIFigure, 'Sucessfully saved model.', 'Sucess', 'Options',{'OK'});
                refreshModelList(app);
                figure(app.UIFigure);
            end
        end

        % Button pushed function: BtnLoadModel
        function BtnLoadModelPushed(app, event)
            selectedModel = app.ListBoxModels.Value;

            if isempty(selectedModel)
                uialert(app.UIFigure, 'Please select model to load', 'Warning');
            else
                loadModel(app, selectedModel);
                uiconfirm(app.UIFigure, 'Sucessfully loaded model', 'Sucess', 'Options',{'OK'});
                cla(app.AxesPrediction);
            end
        end

        % Button pushed function: BtnRemoveModel
        function BtnRemoveModelPushed(app, event)
            selectedModel = app.ListBoxModels.Value;
            if isempty(selectedModel)
                uialert(app.UIFigure, 'Please select model to remove', 'Warning');
            else
                response = uiconfirm(app.UIFigure, ['Are you sure you want to remove ', selectedModel, '?'], 'Confirm Remove', 'Icon', 'warning', 'Options', {'Yes', 'No'}, 'DefaultOption', 2);

                if response == "Yes"
                    delete(fullfile('models', selectedModel))
                    refreshModelList(app)
                else
                    return
                end
            end
        end

        % Button pushed function: BtnTrain
        function BtnTrainPushed(app, event)
            app.isTraining = ~app.isTraining;

            if app.isTraining
                app.BtnTrain.Text = "Stop";
                app.BtnTrain.Icon = "assets/stop.png";
                app.BtnSaveModel.Enable = "off";

                app.LabelTestAccuracy.Text = "";

                [app.Wf, app.Rf, app.bf, app.Wi, app.Ri, app.bi, app.Wg,...
                    app.Rg, app.bg, app.Wo, app.Ro, app.bo, app.V, app.b] = trainNetwork(app);

                app.BtnSaveModel.Enable = "on";
            else
                app.BtnTrain.Text = "Start";
                app.BtnTrain.Icon = "assets/start.png";

                app.BtnSaveModel.Enable = "on";
            end
        end

        % Button pushed function: BtnPredict
        function BtnPredictPushed(app, event)
            selectedFile = app.ListBoxFiles.Value;
            startBalance = app.SpinnerStartBalance.Value;
            
            if isempty(selectedFile)
                uialert(app.UIFigure, 'Please select file to predict', 'Warning');
            elseif numel(app.Wf)==0
                uialert(app.UIFigure, 'Please load the model', 'Warning');
            elseif startBalance == 0 || isempty(startBalance)
                uialert(app.UIFigure, 'Please input start balance', 'Warning');
            else
                dialog = uiprogressdlg(app.UIFigure,'Title','Calculating...','Cancelable','on');
                dialog.Value = 0;

                data = parquetread(fullfile("datasets", selectedFile));
                resampled = resampleDataDaily(data);

                data = sortrows(resampled, 'open_date');

                [Xtest, ~] = getWindowedDataset(data, app.windowSize, false);

                % Normalizing Process
                Xtest = Xtest ./ Xtest(1, :);
                Xtest = (Xtest - Xtest(1, :)) * 10;

                h0 = zeros(app.H, 1);
                c0 = zeros(app.H, 1);
                pred = lstmForward(app.Wf, app.Rf, app.bf, app.Wi, app.Ri, app.bi,...
                    app.Wg, app.Rg, app.bg, app.Wo, app.Ro, app.bo, app.V, app.b, Xtest, h0, c0);

                [~, predIdx] = max(pred);

                dialog.Value = 1;

                originalData = data.close(app.windowSize:end);
                openDates = data.open_date(app.windowSize:end);

                indices1 = predIdx == 1;
                indices2 = predIdx == 2;
                indices3 = predIdx == 3;

                plot(app.AxesPrediction, openDates, originalData, '--');

                grid(app.AxesPrediction, 'on');
                hold(app.AxesPrediction, 'on');

                scatter(app.AxesPrediction, openDates(indices1), originalData(indices1), 'ko');
                scatter(app.AxesPrediction, openDates(indices2), originalData(indices2), 'b^', 'MarkerFaceColor', 'b');
                scatter(app.AxesPrediction, openDates(indices3), originalData(indices3), 'rs', 'MarkerFaceColor', 'r');

                legend(app.AxesPrediction, {'original', 'hold', 'buy', 'sell'});

                hold(app.AxesPrediction, 'off');

                signals = predIdx - 1;
                [finalBalance, pnlAbsolute, pnlPercent, maxDrawdown, tradeCount, winRate, profitFactor, sharpeRatio] = ...
                    analyzeTradingStrategy(startBalance, originalData, signals');

                app.LabelFinalBalance.Text = num2str(finalBalance, '%.2f');
                app.LabelPLAbsolute.Text = num2str(pnlAbsolute, '%.2f');
                app.LabelPLPercent.Text = strcat(num2str(pnlPercent, '%.2f'), ' %');
                app.LabelMaxDrawdown.Text = strcat(num2str(maxDrawdown, '%.2f'), ' %');
                app.LabelTradeCount.Text = num2str(tradeCount);
                app.LabelWinRate.Text = strcat(num2str(winRate, '%.2f'), ' %');
                app.LabelProfitFactor.Text = num2str(profitFactor);
                app.LabelShartRatio.Text = num2str(sharpeRatio);

                close(dialog);

            end
        end

        % Value changed function: ListBoxFiles
        function ListBoxFilesValueChanged(app, event)
            dialog = uiprogressdlg(app.UIFigure,'Title','Loading Dataset...','Cancelable','on');
            dialog.Value = 0;
            plotOriginalData(app);
            dialog.Value = 1;
            close(dialog);
        end

        % Selection changed function: BtnGroupPlotTypeOriginal
        function BtnGroupPlotTypeOriginalSelectionChanged(app, event)
            % selectedButton = app.BtnGroupPlotTypeOriginal.SelectedObject;
            plotOriginalData(app);
        end

        % Callback function: TreeColumnsOriginal
        function TreeColumnsOriginalCheckedNodesChanged(app, event)
            plotOriginalData(app);

        end

        % Value changed function: SpinnerFs
        function SpinnerFsValueChanged(app, event)
            plotOriginalData(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LabelTitle = uilabel(app.UIFigure);
            app.LabelTitle.Text = 'Crypto Trading Assist by LSTM';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabTrading = uitab(app.TabGroup);
            app.TabTrading.Title = 'Trading';
            app.Panel = uipanel(app.TabTrading);
            app.AxesOriginal = uiaxes(app.Panel);
            title(app.AxesOriginal, 'Original Data')
            zlabel(app.AxesOriginal, 'Z')
            app.AxesOriginal.Visible = 'off';
            app.BtnGroupPlotTypeOriginal = uibuttongroup(app.Panel);
            app.BtnGroupPlotTypeOriginal.SelectionChangedFcn = createCallbackFcn(app, @BtnGroupPlotTypeOriginalSelectionChanged, true);
            app.BtnFFTOriginal = uiradiobutton(app.BtnGroupPlotTypeOriginal);
            app.BtnFFTOriginal.Text = 'FFT';
            app.BtnTableOriginal = uiradiobutton(app.BtnGroupPlotTypeOriginal);
            app.BtnTableOriginal.Text = 'Table';
            app.Btn2DPlotOriginal = uiradiobutton(app.BtnGroupPlotTypeOriginal);
            app.Btn2DPlotOriginal.Text = '2D plot';
            app.BtnCandlestickOriginal = uiradiobutton(app.BtnGroupPlotTypeOriginal);
            app.BtnCandlestickOriginal.Text = 'Candlestick';
            app.BtnCandlestickOriginal.Value = true;
            app.TreeColumnsOriginal = uitree(app.Panel, 'checkbox');
            app.TreeNodeAll = uitreenode(app.TreeColumnsOriginal);
            app.TreeNodeAll.Text = 'All';
            app.TreeNodeOpen = uitreenode(app.TreeNodeAll);
            app.TreeNodeOpen.Text = 'open';
            app.TreeNodeClose = uitreenode(app.TreeNodeAll);
            app.TreeNodeClose.Text = 'close';
            app.TreeNodeHigh = uitreenode(app.TreeNodeAll);
            app.TreeNodeHigh.Text = 'high';
            app.TreeNodeLow = uitreenode(app.TreeNodeAll);
            app.TreeNodeLow.Text = 'low';
            % Assign Checked Nodes
            app.TreeColumnsOriginal.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeColumnsOriginalCheckedNodesChanged, true);
            app.TableOriginal = uitable(app.Panel);
            app.TableOriginal.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TableOriginal.RowName = {};
            app.TableOriginal.Visible = 'off';
            app.FsSpinnerLabel = uilabel(app.Panel);
            app.FsSpinnerLabel.Text = 'Fs';
            app.SpinnerFs = uispinner(app.Panel);
            app.SpinnerFs.Limits = [1 Inf];
            app.SpinnerFs.ValueChangedFcn = createCallbackFcn(app, @SpinnerFsValueChanged, true);
            app.SpinnerFs.Enable = 'off';
            app.SpinnerFs.Value = 1000;
            app.ListBoxFiles = uilistbox(app.TabTrading);
            app.ListBoxFiles.Items = {};
            app.ListBoxFiles.ValueChangedFcn = createCallbackFcn(app, @ListBoxFilesValueChanged, true);
            app.ListBoxFiles.Value = {};
            app.DataLabel = uilabel(app.TabTrading);
            app.DataLabel.Text = 'Data';
            app.BtnImportFile = uibutton(app.TabTrading, 'push');
            app.BtnImportFile.ButtonPushedFcn = createCallbackFcn(app, @BtnImportFilePushed, true);
            app.BtnImportFile.Icon = fullfile(pathToMLAPP, 'assets', 'import.png');
            app.BtnImportFile.Text = 'Import...';
            app.BtnRemoveFile = uibutton(app.TabTrading, 'push');
            app.BtnRemoveFile.ButtonPushedFcn = createCallbackFcn(app, @BtnRemoveFilePushed, true);
            app.BtnRemoveFile.Icon = fullfile(pathToMLAPP, 'assets', 'remove.png');
            app.BtnRemoveFile.Text = 'Remove';
            app.PanelPredict = uipanel(app.TabTrading);
            app.AxesPrediction = uiaxes(app.PanelPredict);
            title(app.AxesPrediction, 'Title')
            app.BtnPredict = uibutton(app.PanelPredict, 'push');
            app.BtnPredict.ButtonPushedFcn = createCallbackFcn(app, @BtnPredictPushed, true);
            app.BtnPredict.Icon = fullfile(pathToMLAPP, 'assets', 'predict.png');
            app.BtnPredict.Text = 'Predict';
            app.takeprofitratioSpinnerLabel_5 = uilabel(app.PanelPredict);
            app.takeprofitratioSpinnerLabel_5.Text = 'final balance';
            app.takeprofitratioSpinnerLabel_4 = uilabel(app.PanelPredict);
            app.takeprofitratioSpinnerLabel_4.Text = 'start balance';
            app.takeprofitratioSpinnerLabel_6 = uilabel(app.PanelPredict);
            app.takeprofitratioSpinnerLabel_6.Text = 'P&L absolute';
            app.takeprofitratioSpinnerLabel_7 = uilabel(app.PanelPredict);
            app.takeprofitratioSpinnerLabel_7.Text = 'max drawdown';
            app.takeprofitratioSpinnerLabel_8 = uilabel(app.PanelPredict);
            app.takeprofitratioSpinnerLabel_8.Text = 'trade count';
            app.takeprofitratioSpinnerLabel_9 = uilabel(app.PanelPredict);
            app.takeprofitratioSpinnerLabel_9.Text = 'win rate';
            app.takeprofitratioSpinnerLabel_10 = uilabel(app.PanelPredict);
            app.takeprofitratioSpinnerLabel_10.Text = 'profit factor';
            app.takeprofitratioSpinnerLabel_11 = uilabel(app.PanelPredict);
            app.takeprofitratioSpinnerLabel_11.Text = 'sharp ratio';
            app.SpinnerStartBalance = uispinner(app.PanelPredict);
            app.SpinnerStartBalance.Limits = [0 Inf];
            app.SpinnerStartBalance.ValueDisplayFormat = '%11d';
            app.SpinnerStartBalance.Value = 10000;
            app.LabelFinalBalance = uilabel(app.PanelPredict);
            app.LabelFinalBalance.Text = '';
            app.LabelPLAbsolute = uilabel(app.PanelPredict);
            app.LabelPLAbsolute.Text = '';
            app.LabelMaxDrawdown = uilabel(app.PanelPredict);
            app.LabelMaxDrawdown.Text = '';
            app.LabelTradeCount = uilabel(app.PanelPredict);
            app.LabelTradeCount.Text = '';
            app.LabelWinRate = uilabel(app.PanelPredict);
            app.LabelWinRate.Text = '';
            app.LabelProfitFactor = uilabel(app.PanelPredict);
            app.LabelProfitFactor.Text = '';
            app.LabelShartRatio = uilabel(app.PanelPredict);
            app.LabelShartRatio.Text = '';
            app.takeprofitratioSpinnerLabel_12 = uilabel(app.PanelPredict);
            app.takeprofitratioSpinnerLabel_12.Text = 'P&L percent';
            app.LabelPLPercent = uilabel(app.PanelPredict);
            app.LabelPLPercent.Text = '';
            app.ListBoxModels = uilistbox(app.TabTrading);
            app.ListBoxModels.Items = {};
            app.ListBoxModels.Value = {};
            app.SavedModelLabel = uilabel(app.TabTrading);
            app.SavedModelLabel.Text = 'Saved Model';
            app.BtnLoadModel = uibutton(app.TabTrading, 'push');
            app.BtnLoadModel.ButtonPushedFcn = createCallbackFcn(app, @BtnLoadModelPushed, true);
            app.BtnLoadModel.Text = 'Load';
            app.BtnRemoveModel = uibutton(app.TabTrading, 'push');
            app.BtnRemoveModel.ButtonPushedFcn = createCallbackFcn(app, @BtnRemoveModelPushed, true);
            app.BtnRemoveModel.Text = 'Remove';
            app.LabelLoadedModel = uilabel(app.TabTrading);
            app.LabelLoadedModel.Text = '';
            app.LoadedModelInfoLabel = uilabel(app.TabTrading);
            app.LoadedModelInfoLabel.Text = 'Loaded Model Info';
            app.takeprofitratioSpinnerLabel_3 = uilabel(app.TabTrading);
            app.takeprofitratioSpinnerLabel_3.Text = 'name';
            app.takeprofitratioSpinnerLabel_2 = uilabel(app.TabTrading);
            app.takeprofitratioSpinnerLabel_2.Text = 'TP ratio';
            app.stoplossratioSpinnerLabel_2 = uilabel(app.TabTrading);
            app.stoplossratioSpinnerLabel_2.Text = 'SL ratio';
            app.windowsizeSpinnerLabel_2 = uilabel(app.TabTrading);
            app.windowsizeSpinnerLabel_2.Text = 'window';
            app.hiddennodesSpinnerLabel_2 = uilabel(app.TabTrading);
            app.hiddennodesSpinnerLabel_2.Text = 'hidden nodes';
            app.batchsizeSpinnerLabel_2 = uilabel(app.TabTrading);
            app.batchsizeSpinnerLabel_2.Text = 'batch size';
            app.batchsizeSpinnerLabel_3 = uilabel(app.TabTrading);
            app.batchsizeSpinnerLabel_3.Text = 'params size';
            app.LabelTPRatioPredict = uilabel(app.TabTrading);
            app.LabelTPRatioPredict.Text = '';
            app.LabelSLRatioPredict = uilabel(app.TabTrading);
            app.LabelSLRatioPredict.Text = '';
            app.LabelWindowSizePredict = uilabel(app.TabTrading);
            app.LabelWindowSizePredict.Text = '';
            app.LabelHiddenNodesPredict = uilabel(app.TabTrading);
            app.LabelHiddenNodesPredict.Text = '';
            app.LabelBatchSizePredict = uilabel(app.TabTrading);
            app.LabelBatchSizePredict.Text = '';
            app.LabelParamSizePredict = uilabel(app.TabTrading);
            app.LabelParamSizePredict.Text = '';
            app.TabTraining = uitab(app.TabGroup);
            app.TabTraining.Title = 'Training';
            app.AxesLoss = uiaxes(app.TabTraining);
            title(app.AxesLoss, 'Corss Entropy Loss')
            xlabel(app.AxesLoss, 'epoch')
            app.AxesLoss.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Image = uiimage(app.TabTraining);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'lstm1.png');
            app.takeprofitratioSpinnerLabel = uilabel(app.TabTraining);
            app.takeprofitratioSpinnerLabel.Text = 'take profit ratio';
            app.SpinnerTPRatio = uispinner(app.TabTraining);
            app.SpinnerTPRatio.Step = 0.01;
            app.SpinnerTPRatio.Limits = [0 1];
            app.SpinnerTPRatio.Value = 0.02;
            app.stoplossratioSpinnerLabel = uilabel(app.TabTraining);
            app.stoplossratioSpinnerLabel.Text = 'stop loss ratio';
            app.SpinnerSLRatio = uispinner(app.TabTraining);
            app.SpinnerSLRatio.Step = 0.01;
            app.SpinnerSLRatio.Limits = [0 1];
            app.SpinnerSLRatio.Value = 0.02;
            app.windowsizeSpinnerLabel = uilabel(app.TabTraining);
            app.windowsizeSpinnerLabel.Text = 'window size';
            app.SpinnerWindowSize = uispinner(app.TabTraining);
            app.SpinnerWindowSize.Limits = [0 Inf];
            app.SpinnerWindowSize.Value = 12;
            app.traindataratioSpinnerLabel = uilabel(app.TabTraining);
            app.traindataratioSpinnerLabel.Text = 'train data ratio';
            app.SpinnerTrainRatio = uispinner(app.TabTraining);
            app.SpinnerTrainRatio.Step = 0.01;
            app.SpinnerTrainRatio.Limits = [0 1];
            app.SpinnerTrainRatio.Value = 0.7;
            app.batchsizeSpinnerLabel = uilabel(app.TabTraining);
            app.batchsizeSpinnerLabel.Text = 'batch size';
            app.SpinnerBatchSize = uispinner(app.TabTraining);
            app.SpinnerBatchSize.Limits = [0 Inf];
            app.SpinnerBatchSize.Value = 32;
            app.hiddennodesSpinnerLabel = uilabel(app.TabTraining);
            app.hiddennodesSpinnerLabel.Text = 'hidden nodes';
            app.SpinnerHiddenNodes = uispinner(app.TabTraining);
            app.SpinnerHiddenNodes.Limits = [0 Inf];
            app.SpinnerHiddenNodes.Value = 50;
            app.BtnSaveModel = uibutton(app.TabTraining, 'push');
            app.BtnSaveModel.ButtonPushedFcn = createCallbackFcn(app, @BtnSaveModelPushed, true);
            app.BtnSaveModel.Icon = fullfile(pathToMLAPP, 'assets', 'save.png');
            app.BtnSaveModel.Text = 'Save...';
            app.learningrateEditFieldLabel = uilabel(app.TabTraining);
            app.learningrateEditFieldLabel.Text = 'learning rate';
            app.EditFieldLearningRate = uieditfield(app.TabTraining, 'numeric');
            app.EditFieldLearningRate.Limits = [0 1];
            app.EditFieldLearningRate.Value = 0.0005;
            app.momentumrateEditFieldLabel = uilabel(app.TabTraining);
            app.momentumrateEditFieldLabel.Text = 'momentum rate';
            app.EditFieldMomentumRate = uieditfield(app.TabTraining, 'numeric');
            app.EditFieldMomentumRate.Limits = [0 1];
            app.EditFieldMomentumRate.Value = 0.9;
            app.Label = uilabel(app.TabTraining);
            app.Label.Text = 'Test Accuracy';
            app.LabelTestAccuracy = uilabel(app.TabTraining);
            app.LabelTestAccuracy.Text = '';
            app.DropDownTrainingDataset = uidropdown(app.TabTraining);
            app.DropDownTrainingDataset.Items = {};
            app.DropDownTrainingDataset.Value = {};
            app.datasetDropDownLabel = uilabel(app.TabTraining);
            app.datasetDropDownLabel.Text = 'dataset';
            app.BtnTrain = uibutton(app.TabTraining, 'push');
            app.BtnTrain.ButtonPushedFcn = createCallbackFcn(app, @BtnTrainPushed, true);
            app.BtnTrain.Icon = fullfile(pathToMLAPP, 'assets', 'start.png');
            app.BtnTrain.Text = 'Train';
            app.epochEditFieldLabel = uilabel(app.TabTraining);
            app.epochEditFieldLabel.Text = 'epoch';
            app.EditFieldEpoch = uieditfield(app.TabTraining, 'numeric');
            app.EditFieldEpoch.Limits = [10 100000];
            app.EditFieldEpoch.ValueDisplayFormat = '%11d';
            app.EditFieldEpoch.Value = 300;
            app.dropoutLabel = uilabel(app.TabTraining);
            app.dropoutLabel.Text = 'dropout';
            app.EditFieldDropout = uieditfield(app.TabTraining, 'numeric');
            app.EditFieldDropout.Limits = [0 1];
            app.EditFieldDropout.ValueDisplayFormat = '%11.1g';
            app.EditFieldDropout.Value = 0.2;
            app.BtnClose = uibutton(app.UIFigure, 'push');
            app.BtnClose.ButtonPushedFcn = createCallbackFcn(app, @BtnClosePushed, true);
            app.BtnClose.Icon = fullfile(pathToMLAPP, 'assets', 'close.png');
            app.BtnClose.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = crypto_trading

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end